classdef multiRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        leftPanel                       matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        DronesTab                       matlab.ui.container.Tab
        DRONESDATAINPUTLabel            matlab.ui.control.Label
        NumberofdronesSpinnerLabel      matlab.ui.control.Label
        NumberofdronesSpinner           matlab.ui.control.Spinner
        MassofsingledroneEditFieldLabel  matlab.ui.control.Label
        MassofsingledroneEditField      matlab.ui.control.NumericEditField
        KgLabel                         matlab.ui.control.Label
        ArmlengthofdroneEditFieldLabel  matlab.ui.control.Label
        ArmlengthofdroneEditField       matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        NumberofattachmentpointsSpinnerLabel  matlab.ui.control.Label
        NumberofattachmentpointsSpinner  matlab.ui.control.Spinner
        DronesPosition                  matlab.ui.control.Table
        PayloadTab                      matlab.ui.container.Tab
        PAYLOADDATAINPUTLabel           matlab.ui.control.Label
        MassofpayloadEditFieldLabel     matlab.ui.control.Label
        MassofpayloadEditField          matlab.ui.control.NumericEditField
        KgLabel_2                       matlab.ui.control.Label
        TypeofPayloadDropDownLabel      matlab.ui.control.Label
        TypeofPayloadDropDown           matlab.ui.control.DropDown
        widthEditFieldLabel             matlab.ui.control.Label
        widthEditField                  matlab.ui.control.NumericEditField
        heightEditFieldLabel            matlab.ui.control.Label
        heightEditField                 matlab.ui.control.NumericEditField
        lengthEditFieldLabel            matlab.ui.control.Label
        lengthEditField                 matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        CoMPosition                     matlab.ui.control.Table
        TabGroup4                       matlab.ui.container.TabGroup
        TrajectoryTab                   matlab.ui.container.Tab
        LifttimeEditFieldLabel          matlab.ui.control.Label
        LifttimeEditField               matlab.ui.control.NumericEditField
        HovertimeEditFieldLabel         matlab.ui.control.Label
        HovertimeEditField              matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        LiftheightEditFieldLabel        matlab.ui.control.Label
        LiftheightEditField             matlab.ui.control.NumericEditField
        mLabel_5                        matlab.ui.control.Label
        DTrajectoryTab                  matlab.ui.container.Tab
        TrajectoryDropDownLabel         matlab.ui.control.Label
        TrajectoryDropDown              matlab.ui.control.DropDown
        RadiusLabel                     matlab.ui.control.Label
        Data1                           matlab.ui.control.NumericEditField
        mLabel_6                        matlab.ui.control.Label
        AnglefromhorizontalEditFieldLabel  matlab.ui.control.Label
        Data2                           matlab.ui.control.NumericEditField
        LabelT                          matlab.ui.control.Label
        AccelerationTab                 matlab.ui.container.Tab
        KpEditFieldLabel                matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        KdEditField                     matlab.ui.control.NumericEditField
        LiftandhoverLabel               matlab.ui.control.Label
        DTrajectoryLabel                matlab.ui.control.Label
        KpEditField_2Label              matlab.ui.control.Label
        KpTrajEditField                 matlab.ui.control.NumericEditField
        KdEditField_2Label              matlab.ui.control.Label
        KdTrajEditField                 matlab.ui.control.NumericEditField
        DATAINPUTLabel                  matlab.ui.control.Label
        CONTROLPARAMETERSLabel          matlab.ui.control.Label
        rightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DTab                            matlab.ui.container.Tab
        beamPlot2D                      matlab.ui.control.UIAxes
        DTab_2                          matlab.ui.container.Tab
        beamplot3D                      matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        PositionTab                     matlab.ui.container.Tab
        Positiongraph                   matlab.ui.control.UIAxes
        ForceTab                        matlab.ui.container.Tab
        Forcegraph                      matlab.ui.control.UIAxes
        TrajectoryTopTab                matlab.ui.container.Tab
        Topview                         matlab.ui.control.UIAxes
        TrajectoryRightTab              matlab.ui.container.Tab
        Rightview                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function ReadData(app)
            global n_i m_i L_i 
            global M_b B
            global pa
            global times z_f
            global K
            [n_i,m_i,L_i] = readDrone(app);
            [M_b] = readPayload(app);
            [B] = readB(app);
            [pa] = attachmentPoints(app);
            [times,z_f] = readTraj(app);
            read3Dtraj(app);
            [K] = gainsAcc(app);
        end
        
        function [n_i,m_i,L_i] = readDrone(app)
            %Drones Data Input
            n_i = app.NumberofdronesSpinner.Value ;   %number of drones
            m_i = app.MassofsingledroneEditField.Value ;  %mass of single drone
            L_i = app.ArmlengthofdroneEditField.Value ; %length of arm from drone 
        end
      
        function  [M_b] = readPayload(app)
            global beam 
            %Payload Data Input 
            M_b = app.MassofpayloadEditField.Value ;  %mass of payload
            w_b = app.widthEditField.Value ;          %width of beam
            h_b = app.heightEditField.Value ;         %height of beam
            l_b = app.lengthEditField.Value ;         %length of beam
            beam = [w_b h_b l_b];
        end
        
        function [B] = readB(app)
            global B
            B = app.CoMPosition.DisplayData;
        end 
        
        function [pa] = attachmentPoints(app)
            global beam
            l_b = beam(3);
            %attachment points
            a_i = app.NumberofattachmentpointsSpinner.Value ;  %number of attachment points
            s_i = a_i-1 ; %segments in payload due to attachment points
            pa = zeros(a_i,1);
            for i=1:a_i
                pa(i) = 0 + (i-1)*l_b/s_i;
            end
        end
       
        function [times,z_f] = readTraj(app)
            %get desired times 
            t_l = app.LifttimeEditField.Value;         %lift time value
            t_h = app.HovertimeEditField.Value + t_l;  %final hover time value
            times = [0,t_l,t_h];
            
            %get desired height
            z_f = app.LiftheightEditField.Value; %final height value
        end
        
        function  read3Dtraj(app)
            global  data1_traj data2_traj path_b
            data1_traj = app.Data1.Value;
            data2_traj = app.Data2.Value;
            value = app.TrajectoryDropDown.Value;
            switch value
                case 'Circle'
                    path_b = 'c';
                case 'Square'
                    path_b = 's';
                case 'Line'
                    path_b = 'l';
            end 
        end
        
        function [K] = gainsAcc(app)
            %PD control gains
            Kp = app.KpEditField.Value;  %read proportional gain value
            Kd = app.KdEditField.Value;  %read derivative gain value
            Kpt = app.KpTrajEditField.Value;  %read proportional gain value for 3D trajectory
            Kdt = app.KdTrajEditField.Value;  %read derivative gain value for 3D trajectory
            K = [Kp,Kd,Kpt,Kdt];
        end
        
        
        function [X] = updateTableDrone(app)
            global beam n_i pa 
            w_b = beam(1);h_b = beam(2);
            dat = zeros(n_i,4);
            X = zeros(1,n_i);
            %retrieve data from table 
            t_i = get(app.DronesPosition,'data');
            Pa_i = t_i(:,4);
                %table 
                for i=1:n_i
                    dat(i,:) = [pa(Pa_i(i)),w_b/2,h_b,Pa_i(i)];
                    rName(i,:) = ['Drone ' num2str(i)];
                    X(i) = pa(Pa_i(i));
                end
            t = (dat);
            %Add data to the Table UI component
            app.DronesPosition.Data = t;
            app.DronesPosition.RowName = rName;
        end

        
        function plot2Dbeam(app,h_b,l_b,pa,X)
            global B
            %plot beam with drones in 2D
            cla(app.beamPlot2D) %clears plot so it can plot new graph for next trial
            rectangle(app.beamPlot2D,'Position', [0 0 l_b h_b],"FaceColor",[0,0.5,0.5])
            hold(app.beamPlot2D, 'on') 
            grid(app.beamPlot2D, 'on')
            %plot attachment points
            col = size(pa);
            xaxis = h_b*ones(col(1),1);
            plot(app.beamPlot2D, pa, xaxis, 'ks',...
                'MarkerSize', 10,...
                'MarkerFaceColor',[0,0,0])
            %plot CoM of Beam
            plot(app.beamPlot2D, B(1),B(3), 'cd',...
            'MarkerSize', 6,...
            'MarkerFaceColor',[0,1,1])
            %plot Drones position
            col = size(X);
            x_axis = h_b*ones(col(1),1);
            plot(app.beamPlot2D, X,x_axis, 'r*',...
            'MarkerSize', 6,...
            'MarkerFaceColor',[1,0,0])
            xlim(app.beamPlot2D, [0 l_b])
            ylim(app.beamPlot2D, [0,h_b+0.8])
            legend(app.beamPlot2D,'attachment points',...
                'CoM','Drones location')
            legend(app.beamPlot2D, 'boxoff')
            hold(app.beamPlot2D, 'off') 
        end
        
        function graphPosition(app,zpos,zdes)
            cla(app.Positiongraph) %clears plot so it can plot new graph for next trial
            global dt th
            time = 0:dt:th;
            plot(app.Positiongraph, time,zpos)
            grid(app.Positiongraph, 'on') 
            hold(app.Positiongraph, 'on') 
            plot(app.Positiongraph,time,zdes,'--')
            legend(app.Positiongraph,'actual position','desired position','Location','southeast')
            legend(app.Positiongraph,'boxoff')
        end
        
        function graphForce(app,Force)
            global n_i tf dt
            cla(app.Forcegraph) %clears plot so it can plot new graph for next trial
            time = 0:dt:tf;
            grid(app.Forcegraph,'on') 
            hold(app.Forcegraph, 'on') 
            nameleg = [];
            for i=1:n_i
                plot(app.Forcegraph,time,Force(:,i))
                name = ['Drone ', num2str(i)]; 
                nameleg = [nameleg;name];
            end 
            legend(app.Forcegraph,nameleg,'Location','southeast');
            legend(app.Forcegraph,'boxoff')
        end
        
        function initbeam3D(app,p)
            global point1
            cla(app.beamplot3D)
            grid(app.beamplot3D, 'on')
            hold(app.beamplot3D, 'on')
            xlabel(app.beamplot3D,'longitudinal position [m]')
            ylabel(app.beamplot3D,'lateral position [m]')
            zlabel(app.beamplot3D,'height [m]')
            
            %p
            point1 = plot3(app.beamplot3D,p(1),p(2),p(3),'cd',...
            'MarkerSize', 8,...
            'LineWidth',1,...
            'MarkerFaceColor',[0,1,1]);
        end
        
        function plotref(app,p,R)
            global point2
            xb_axis = R(:,1)/3;
            yb_axis = R(:,2)/3;
            zb_axis = R(:,3);
            %p
            point2 = plot3(app.beamplot3D,p(1),p(2),p(3),'r*',...
            'MarkerSize', 8,...
            'LineWidth',1,...
            'MarkerFaceColor',[1,0,0]);
            %x axis red
            %y axis green
            %z axis blue
            plot3(app.beamplot3D,[p(1),p(1)+xb_axis(1)],[p(2),p(2)+xb_axis(2)],[p(3),p(3)+xb_axis(3)],'r','LineWidth',1)
            plot3(app.beamplot3D,[p(1),p(1)+yb_axis(1)],[p(2),p(2)+yb_axis(2)],[p(3),p(3)+yb_axis(3)],'g','LineWidth',1)
            plot3(app.beamplot3D,[p(1),p(1)+zb_axis(1)],[p(2),p(2)+zb_axis(2)],[p(3),p(3)+zb_axis(3)],'b','LineWidth',1)
        
            xmin = -3;xmax = 6.5;
            ymin = -3;ymax = 6.5;
            zmin = 0;zmax = 6.5; 
            axis(app.beamplot3D,[xmin xmax ymin ymax zmin zmax])
        end
        
        function plotbeam3D(app,p,beam)
            
            %rectangular prism
            %need width(w),height(h),length(l) in meters
            w = beam(1);h = beam(2);l = beam(3);
        
            %face 2
            l_start = 0;l_end = l;
            w_start = 0;w_end = w;%el prisma va hacia atras de mi dibujo
            h_start = 0;h_end = h;
            vert = [l_start w_start h_start;...
                    l_end w_start h_start;...
                    l_end w_end h_start;...
                    l_start w_end h_start;...
                    l_start w_start h_end;...
                    l_end w_start h_end;...
                    l_end w_end h_end;...
                    l_start w_end h_end;...
                    ];
            fac = [1 2 6 5;2 3 7 6;3 4 8 7;4 1 5 8;1 2 3 4;5 6 7 8]; 
            patch(app.beamplot3D,'Vertices',vert,'Faces',fac,'FaceVertexCData',hsv(6),'FaceColor','w','FaceAlpha','0.3')
            view(app.beamplot3D,3)
        end
        
        function plot_traj3D(app,Bx,By,Bz,Qx,Qy,Qz)
            global n point1 point2 cp
            plot3(app.beamplot3D,Bx,By,Bz, '--')
            for i=1:n
               point3 = plot3(app.beamplot3D,Qx(i,:),Qy(i,:),Qz(i,:), '--');
            end
%             plot3(app.beamplot3D,cp(1,:),cp(2,:),cp(3,:));
            legend(app.beamplot3D,[point1 point2 point3],{'CoM','Drones location','Trajectory'},'Location','northeast')
        end
        
        function plot3Dtrajviews(app,Bx,By,Bz,Qx,Qy,Qz)
            global n cp th dt
            cla(app.Topview) %clears plot so it can plot new graph for next trial
            plot(app.Topview,cp(1,:),cp(2,:),'b')
            grid(app.Topview, 'on')
            hold(app.Topview, 'on')
            plot(app.Topview,Bx,By,'--r')
            for i=1:n
               plot(app.Topview,Qx(i,:),Qy(i,:), ':k');
            end
            legend(app.Topview,'desired trajectory', 'CoM trajectory','Drone trajectory','Location','southeast')
            legend(app.Topview,'boxoff')
            
            cla(app.Rightview) %clears plot so it can plot new graph for next trial
            plot(app.Rightview,cp(2,:),cp(3,:),'b')
            grid(app.Rightview, 'on')
            hold(app.Rightview, 'on') 
            st = th/dt; %start point
            plot(app.Rightview,By(st:end),Bz(st:end),'--r')
            for i=1:n
               plot(app.Rightview,Qy(i,st:end),Qz(i,st:end), ':k');
            end
            legend(app.Rightview,'desired trajectory', 'CoM trajectory','Drone trajectory','Location','southeast')
            legend(app.Rightview,'boxoff')
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global n_i 
            global beam 
            global pa 
            ReadData(app);
            
            w_b = beam(1);h_b = beam(2);l_b = beam(3);
            dat_i = zeros(n_i,4);
            X = zeros(1,n_i);
            %table Drones
                for i=1:n_i
                    dat_i(i,:) = [pa(i),w_b/2,h_b,i];
                    rName_i(i,:) = ['Drone ' num2str(i)];
                    X(i) = pa(i);
                end
            t_i = (dat_i);
            %Add data to the Table UI component
            app.DronesPosition.Data = t_i;
            app.DronesPosition.RowName = rName_i;
            
            %table Payload
            dat_b = [l_b/2 w_b/2 h_b];
            rName_b = {'Position of CoM'};
            t_b = (dat_b);
            %Add data to the Table UI component
            app.CoMPosition.Data = t_b;
            app.CoMPosition.RowName = rName_b;
            
        end

        % Callback function: StartButton, UIFigure
        function sendValues(app, event)
            global n_i m_i L_i 
            global M_b B beam 
            global pa 
            global times z_f
            global K
            ReadData(app);
            [X] = updateTableDrone(app);
            h_b = beam(2);l_b = beam(3);
            plot2Dbeam(app,h_b,l_b,pa,X);
            mass = M_b+n_i*m_i;
            y_i = beam(1)/2*ones(1,n_i);
            z_i = beam(2)*ones(1,n_i);
            Q = [X;y_i;z_i];
            [zpos,zdes,Force,R,Rb,Bx,By,Bz,Qx,Qy,Qz] = DSCCsim2 (mass,B,Q,beam,L_i,times,z_f,K);
            graphPosition(app,zpos,zdes)
            graphForce(app,Force)
            initbeam3D(app,B)
            plotref(app,B,Rb);
            qc = size(Q);
            qcol = qc(2);
            for i=1:qcol
                plotref(app,Q(:,i),R);
            end
            plotbeam3D(app,B,beam)
            plot_traj3D(app,Bx,By,Bz,Qx,Qy,Qz)
            plot3Dtrajviews(app,Bx,By,Bz,Qx,Qy,Qz)
        end

        % Display data changed function: DronesPosition
        function DronesPositionDisplayDataChanged(app, event)
            updateTableDrone(app);
        end

        % Value changed function: NumberofdronesSpinner
        function NumberofdronesSpinnerValueChanged(app, event)
            startupFcn(app);
        end

        % Value changed function: TrajectoryDropDown
        function TrajectoryDropDownValueChanged(app, event)
            value = app.TrajectoryDropDown.Value;
            switch value
                case 'Circle'
                    app.RadiusLabel.Position = [79 40 43 22];
                    app.RadiusLabel.Text = 'Radius';
                    app.AnglefromhorizontalEditFieldLabel.Text = 'Angle from horizontal';
                    app.LabelT.Text = '°';
                case 'Square'
                    app.RadiusLabel.Position = [20 40 100 22];
                    app.RadiusLabel.Text = 'Side length';
                    app.Data1.Value = 1;
                    app.AnglefromhorizontalEditFieldLabel.Text = 'Time';
                    app.Data2.Value = 10;
                    app.LabelT.Text = 's';
                case 'Line'
                    app.RadiusLabel.Position = [25 40 100 22];
                    app.RadiusLabel.Text = 'Distance';
                    app.Data1.Value = 1;
                    app.AnglefromhorizontalEditFieldLabel.Text = 'Time';
                    app.Data2.Value = 10;
                    app.LabelT.Text = 's';                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @sendValues, true);
            app.leftPanel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.leftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @sendValues, true);
            app.StartButton.Text = 'Start';
            app.TabGroup3 = uitabgroup(app.leftPanel);
            app.DronesTab = uitab(app.TabGroup3);
            app.DronesTab.Title = 'Drones';
            app.DRONESDATAINPUTLabel = uilabel(app.DronesTab);
            app.DRONESDATAINPUTLabel.Text = 'DRONES DATA INPUT';
            app.NumberofdronesSpinnerLabel = uilabel(app.DronesTab);
            app.NumberofdronesSpinnerLabel.Text = 'Number of drones ';
            app.NumberofdronesSpinner = uispinner(app.DronesTab);
            app.NumberofdronesSpinner.Limits = [2 5];
            app.NumberofdronesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofdronesSpinnerValueChanged, true);
            app.NumberofdronesSpinner.Value = 2;
            app.MassofsingledroneEditFieldLabel = uilabel(app.DronesTab);
            app.MassofsingledroneEditFieldLabel.Text = 'Mass of single drone ';
            app.MassofsingledroneEditField = uieditfield(app.DronesTab, 'numeric');
            app.MassofsingledroneEditField.Limits = [0 3];
            app.MassofsingledroneEditField.Value = 1.6;
            app.KgLabel = uilabel(app.DronesTab);
            app.KgLabel.Text = 'Kg';
            app.ArmlengthofdroneEditFieldLabel = uilabel(app.DronesTab);
            app.ArmlengthofdroneEditFieldLabel.Text = 'Arm length of drone ';
            app.ArmlengthofdroneEditField = uieditfield(app.DronesTab, 'numeric');
            app.ArmlengthofdroneEditField.Limits = [0 3];
            app.ArmlengthofdroneEditField.Value = 0.225;
            app.mLabel = uilabel(app.DronesTab);
            app.mLabel.Text = 'm';
            app.NumberofattachmentpointsSpinnerLabel = uilabel(app.DronesTab);
            app.NumberofattachmentpointsSpinnerLabel.Text = 'Number of attachment points';
            app.NumberofattachmentpointsSpinner = uispinner(app.DronesTab);
            app.NumberofattachmentpointsSpinner.Limits = [2 5];
            app.NumberofattachmentpointsSpinner.Value = 3;
            app.DronesPosition = uitable(app.DronesTab);
            app.DronesPosition.ColumnName = {'X_position'; 'Y_position'; 'Z_position'; 'Attachment Point'};
            app.DronesPosition.RowName = {};
            app.DronesPosition.ColumnEditable = [false false false true];
            app.DronesPosition.DisplayDataChangedFcn = createCallbackFcn(app, @DronesPositionDisplayDataChanged, true);
            app.PayloadTab = uitab(app.TabGroup3);
            app.PayloadTab.Title = 'Payload';
            app.PAYLOADDATAINPUTLabel = uilabel(app.PayloadTab);
            app.PAYLOADDATAINPUTLabel.Text = 'PAYLOAD DATA INPUT ';
            app.MassofpayloadEditFieldLabel = uilabel(app.PayloadTab);
            app.MassofpayloadEditFieldLabel.Text = 'Mass of payload';
            app.MassofpayloadEditField = uieditfield(app.PayloadTab, 'numeric');
            app.MassofpayloadEditField.Limits = [0 2];
            app.MassofpayloadEditField.Value = 0.7;
            app.KgLabel_2 = uilabel(app.PayloadTab);
            app.KgLabel_2.Text = 'Kg';
            app.TypeofPayloadDropDownLabel = uilabel(app.PayloadTab);
            app.TypeofPayloadDropDownLabel.Text = 'Type of Payload';
            app.TypeofPayloadDropDown = uidropdown(app.PayloadTab);
            app.TypeofPayloadDropDown.Items = {'Beam ', 'Rod'};
            app.TypeofPayloadDropDown.Value = 'Beam ';
            app.widthEditFieldLabel = uilabel(app.PayloadTab);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.PayloadTab, 'numeric');
            app.widthEditField.Limits = [0 1];
            app.widthEditField.Value = 0.3;
            app.heightEditFieldLabel = uilabel(app.PayloadTab);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.PayloadTab, 'numeric');
            app.heightEditField.Limits = [0 1];
            app.heightEditField.Value = 0.7;
            app.lengthEditFieldLabel = uilabel(app.PayloadTab);
            app.lengthEditFieldLabel.Text = 'length';
            app.lengthEditField = uieditfield(app.PayloadTab, 'numeric');
            app.lengthEditField.Limits = [0 100];
            app.lengthEditField.Value = 2;
            app.mLabel_2 = uilabel(app.PayloadTab);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.PayloadTab);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.PayloadTab);
            app.mLabel_4.Text = 'm';
            app.CoMPosition = uitable(app.PayloadTab);
            app.CoMPosition.ColumnName = {'x'; 'y'; 'z'};
            app.CoMPosition.RowName = {'X'; 'Y'; 'Z'};
            app.CoMPosition.ColumnEditable = true;
            app.TabGroup4 = uitabgroup(app.leftPanel);
            app.TrajectoryTab = uitab(app.TabGroup4);
            app.TrajectoryTab.Title = 'Trajectory';
            app.LifttimeEditFieldLabel = uilabel(app.TrajectoryTab);
            app.LifttimeEditFieldLabel.Text = 'Lift time ';
            app.LifttimeEditField = uieditfield(app.TrajectoryTab, 'numeric');
            app.LifttimeEditField.Limits = [0 100];
            app.LifttimeEditField.Value = 15;
            app.HovertimeEditFieldLabel = uilabel(app.TrajectoryTab);
            app.HovertimeEditFieldLabel.Text = 'Hover time ';
            app.HovertimeEditField = uieditfield(app.TrajectoryTab, 'numeric');
            app.HovertimeEditField.Limits = [0 100];
            app.HovertimeEditField.Value = 15;
            app.sLabel = uilabel(app.TrajectoryTab);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.TrajectoryTab);
            app.sLabel_2.Text = 's';
            app.LiftheightEditFieldLabel = uilabel(app.TrajectoryTab);
            app.LiftheightEditFieldLabel.Text = 'Lift height';
            app.LiftheightEditField = uieditfield(app.TrajectoryTab, 'numeric');
            app.LiftheightEditField.Limits = [0 100];
            app.LiftheightEditField.Value = 3.7;
            app.mLabel_5 = uilabel(app.TrajectoryTab);
            app.mLabel_5.Text = 'm';
            app.DTrajectoryTab = uitab(app.TabGroup4);
            app.DTrajectoryTab.Title = '3D Trajectory';
            app.TrajectoryDropDownLabel = uilabel(app.DTrajectoryTab);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.TrajectoryDropDown = uidropdown(app.DTrajectoryTab);
            app.TrajectoryDropDown.Items = {'Circle', 'Square', 'Line'};
            app.TrajectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TrajectoryDropDownValueChanged, true);
            app.TrajectoryDropDown.Value = 'Circle';
            app.RadiusLabel = uilabel(app.DTrajectoryTab);
            app.RadiusLabel.Text = 'Radius';
            app.Data1 = uieditfield(app.DTrajectoryTab, 'numeric');
            app.Data1.Limits = [1 Inf];
            app.Data1.Value = 1;
            app.mLabel_6 = uilabel(app.DTrajectoryTab);
            app.mLabel_6.Text = 'm';
            app.AnglefromhorizontalEditFieldLabel = uilabel(app.DTrajectoryTab);
            app.AnglefromhorizontalEditFieldLabel.Text = 'Angle from horizontal';
            app.Data2 = uieditfield(app.DTrajectoryTab, 'numeric');
            app.Data2.Limits = [0 360];
            app.Data2.Value = 45;
            app.LabelT = uilabel(app.DTrajectoryTab);
            app.LabelT.Text = '°';
            app.AccelerationTab = uitab(app.TabGroup4);
            app.AccelerationTab.Title = 'Acceleration ';
            app.KpEditFieldLabel = uilabel(app.AccelerationTab);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.AccelerationTab, 'numeric');
            app.KpEditField.Limits = [0 30];
            app.KpEditField.Value = 10;
            app.KdEditFieldLabel = uilabel(app.AccelerationTab);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.AccelerationTab, 'numeric');
            app.KdEditField.Limits = [0 30];
            app.KdEditField.Value = 0.5;
            app.LiftandhoverLabel = uilabel(app.AccelerationTab);
            app.LiftandhoverLabel.Text = 'Lift and hover ';
            app.DTrajectoryLabel = uilabel(app.AccelerationTab);
            app.DTrajectoryLabel.Text = '3D Trajectory';
            app.KpEditField_2Label = uilabel(app.AccelerationTab);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpTrajEditField = uieditfield(app.AccelerationTab, 'numeric');
            app.KpTrajEditField.Limits = [0 100];
            app.KpTrajEditField.Value = 20;
            app.KdEditField_2Label = uilabel(app.AccelerationTab);
            app.KdEditField_2Label.Text = 'Kd';
            app.KdTrajEditField = uieditfield(app.AccelerationTab, 'numeric');
            app.KdTrajEditField.Limits = [0 100];
            app.KdTrajEditField.Value = 3;
            app.DATAINPUTLabel = uilabel(app.leftPanel);
            app.DATAINPUTLabel.Text = 'DATA INPUT';
            app.CONTROLPARAMETERSLabel = uilabel(app.leftPanel);
            app.CONTROLPARAMETERSLabel.Text = 'CONTROL PARAMETERS';
            app.rightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.rightPanel);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '2D ';
            app.beamPlot2D = uiaxes(app.DTab);
            title(app.beamPlot2D, 'Payload view in 2D')
            xlabel(app.beamPlot2D, 'Length [m]')
            ylabel(app.beamPlot2D, 'Height [m]')
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '3D';
            app.beamplot3D = uiaxes(app.DTab_2);
            title(app.beamplot3D, 'Payload view in 3D')
            xlabel(app.beamplot3D, 'X')
            ylabel(app.beamplot3D, 'Y')
            app.TabGroup2 = uitabgroup(app.rightPanel);
            app.PositionTab = uitab(app.TabGroup2);
            app.PositionTab.Title = 'Position';
            app.Positiongraph = uiaxes(app.PositionTab);
            title(app.Positiongraph, 'Position of payload')
            xlabel(app.Positiongraph, 'time [s]')
            ylabel(app.Positiongraph, 'z position [m]')
            app.ForceTab = uitab(app.TabGroup2);
            app.ForceTab.Title = 'Force';
            app.Forcegraph = uiaxes(app.ForceTab);
            title(app.Forcegraph, 'Neccesary Force by each quadcopter')
            xlabel(app.Forcegraph, 'time [s]')
            ylabel(app.Forcegraph, 'Force [N]')
            app.TrajectoryTopTab = uitab(app.TabGroup2);
            app.TrajectoryTopTab.Title = 'Trajectory Top';
            app.Topview = uiaxes(app.TrajectoryTopTab);
            title(app.Topview, '3D trajectory Top View')
            xlabel(app.Topview, 'x [m]')
            ylabel(app.Topview, 'y [m]')
            app.TrajectoryRightTab = uitab(app.TabGroup2);
            app.TrajectoryRightTab.Title = 'Trajectory Right';
            app.Rightview = uiaxes(app.TrajectoryRightTab);
            title(app.Rightview, '3D trajectory Right view')
            xlabel(app.Rightview, 'y [m]')
            ylabel(app.Rightview, 'z [m]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end